classdef Tigerv3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OversightTabGroup              matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        resetMapButton                 matlab.ui.control.Button
        SelectDirectoryButton          matlab.ui.control.Button
        LoginButton                    matlab.ui.control.Button
        DirectoryLabel                 matlab.ui.control.Label
        importMapButton                matlab.ui.control.Button
        FlagunflagallElectrodesCheckBox  matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_22       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_21       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_20       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_19       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_18       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_17       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_16       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_15       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_14       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_13       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_12       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_11       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_10       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_9        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_8        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_7        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_6        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_5        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_4        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_3        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_2        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_1        matlab.ui.control.CheckBox
        FlaggedLabel                   matlab.ui.control.Label
        ComfortLabel                   matlab.ui.control.Label
        ThresholdLabel                 matlab.ui.control.Label
        Comfort_22                     matlab.ui.control.NumericEditField
        Comfort_21                     matlab.ui.control.NumericEditField
        Comfort_20                     matlab.ui.control.NumericEditField
        Comfort_19                     matlab.ui.control.NumericEditField
        Comfort_18                     matlab.ui.control.NumericEditField
        Comfort_17                     matlab.ui.control.NumericEditField
        Comfort_16                     matlab.ui.control.NumericEditField
        Comfort_15                     matlab.ui.control.NumericEditField
        Comfort_14                     matlab.ui.control.NumericEditField
        Comfort_13                     matlab.ui.control.NumericEditField
        Comfort_12                     matlab.ui.control.NumericEditField
        Comfort_11                     matlab.ui.control.NumericEditField
        Comfort_10                     matlab.ui.control.NumericEditField
        Comfort_9                      matlab.ui.control.NumericEditField
        Comfort_8                      matlab.ui.control.NumericEditField
        Comfort_7                      matlab.ui.control.NumericEditField
        Comfort_6                      matlab.ui.control.NumericEditField
        Comfort_5                      matlab.ui.control.NumericEditField
        Comfort_4                      matlab.ui.control.NumericEditField
        Comfort_3                      matlab.ui.control.NumericEditField
        Comfort_2                      matlab.ui.control.NumericEditField
        Comfort_1                      matlab.ui.control.NumericEditField
        Threshold_22                   matlab.ui.control.NumericEditField
        Threshold_21                   matlab.ui.control.NumericEditField
        Threshold_20                   matlab.ui.control.NumericEditField
        Threshold_19                   matlab.ui.control.NumericEditField
        Threshold_18                   matlab.ui.control.NumericEditField
        Threshold_17                   matlab.ui.control.NumericEditField
        Threshold_16                   matlab.ui.control.NumericEditField
        Threshold_15                   matlab.ui.control.NumericEditField
        Threshold_14                   matlab.ui.control.NumericEditField
        Threshold_13                   matlab.ui.control.NumericEditField
        Threshold_12                   matlab.ui.control.NumericEditField
        Threshold_11                   matlab.ui.control.NumericEditField
        Threshold_10                   matlab.ui.control.NumericEditField
        Threshold_9                    matlab.ui.control.NumericEditField
        Threshold_8                    matlab.ui.control.NumericEditField
        Threshold_7                    matlab.ui.control.NumericEditField
        Threshold_6                    matlab.ui.control.NumericEditField
        Threshold_5                    matlab.ui.control.NumericEditField
        Threshold_4                    matlab.ui.control.NumericEditField
        Threshold_3                    matlab.ui.control.NumericEditField
        Threshold_2                    matlab.ui.control.NumericEditField
        Threshold_1                    matlab.ui.control.NumericEditField
        ExperimentTabGroup             matlab.ui.container.TabGroup
        TestSequenceTab                matlab.ui.container.Tab
        AmplitudesButton               matlab.ui.control.Button
        RunTest                        matlab.ui.control.Button
        ElectrodesButton               matlab.ui.control.Button
        SequenceNameLabel              matlab.ui.control.Label
        ChooseSequenceFileButton       matlab.ui.control.Button
        UIAxes_3                       matlab.ui.control.UIAxes
        DiscrimationExpTab             matlab.ui.container.Tab
        TokenOrderDropDown_2           matlab.ui.control.DropDown
        TokenOrderDropDown_2Label      matlab.ui.control.Label
        TokenpatternDropDown_2         matlab.ui.control.DropDown
        TokenpatternDropDown_2Label    matlab.ui.control.Label
        RandomTokenOrderCheckBox_2     matlab.ui.control.CheckBox
        AmplitudesButton_2             matlab.ui.control.Button
        ElectrodesButton_2             matlab.ui.control.Button
        RunsEditField_2                matlab.ui.control.EditField
        RunsEditField_2Label           matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        FolderNameLabel                matlab.ui.control.Label
        ChooseSequenceFolderButton_2   matlab.ui.control.Button
        RunDiscrimination              matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        RankingExpTab                  matlab.ui.container.Tab
        TokenOrderDropDown             matlab.ui.control.DropDown
        TokenOrderDropDownLabel        matlab.ui.control.Label
        TokenpatternDropDown           matlab.ui.control.DropDown
        TokenpatternDropDownLabel      matlab.ui.control.Label
        RandomTokenOrderCheckBox       matlab.ui.control.CheckBox
        PickfilesButton                matlab.ui.control.Button
        AmplitudesEditField            matlab.ui.control.EditField
        AmplitudesEditFieldLabel       matlab.ui.control.Label
        ElectrodesEditField            matlab.ui.control.EditField
        ElectrodesEditFieldLabel       matlab.ui.control.Label
        AmplitudesButton_3             matlab.ui.control.Button
        ElectrodesButton_3             matlab.ui.control.Button
        RunsEditField                  matlab.ui.control.EditField
        RunsEditFieldLabel             matlab.ui.control.Label
        UITable_2                      matlab.ui.control.Table
        FolderNameLabel_2              matlab.ui.control.Label
        ChooseSequenceFolderButton_3   matlab.ui.control.Button
        RunRanking                     matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        PatientIDEditField             matlab.ui.control.EditField
        PatientIDEditFieldLabel        matlab.ui.control.Label
        GenerateSeqTab                 matlab.ui.container.Tab
        ZoomfactorSpinner              matlab.ui.control.Spinner
        ZoomfactorSpinnerLabel         matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        MaxPulsesLabel                 matlab.ui.control.Label
        RateEditField_inms             matlab.ui.control.NumericEditField
        BurstperiodppsLabel_2          matlab.ui.control.Label
        FrameperiodLabel               matlab.ui.control.Label
        WaitPeriodEditField            matlab.ui.control.NumericEditField
        BurstGapmsLabel                matlab.ui.control.Label
        InterpulsegapEditField         matlab.ui.control.NumericEditField
        InterpulsegapmsLabel           matlab.ui.control.Label
        PulsesperburstEditField        matlab.ui.control.NumericEditField
        PulsesperFrameLabel            matlab.ui.control.Label
        RateEditField_inpps            matlab.ui.control.NumericEditField
        BurstperiodppsLabel            matlab.ui.control.Label
        InputgapsButton                matlab.ui.control.Button
        FilenameEditField              matlab.ui.control.EditField
        SaveAsButton                   matlab.ui.control.Button
        UIAxes_only                    matlab.ui.control.UIAxes
        DataAnalysisTab                matlab.ui.container.Tab
        TabGroup                       matlab.ui.container.TabGroup
        DiscriminationPlotTab          matlab.ui.container.Tab
        RankingPlotTab                 matlab.ui.container.Tab
        needautomatedlognamingLabel    matlab.ui.control.Label
        nameofLogorderorsoLabel        matlab.ui.control.Label
        createPlotButton               matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        usuallydirectorylikenormallydisorrankinglogfolderLabel  matlab.ui.control.Label
        FileorFoldertouseEditField     matlab.ui.control.EditField
        FileorFoldertouseEditFieldLabel  matlab.ui.control.Label
        PatientNameEditField           matlab.ui.control.EditField
        PatientNameEditFieldLabel      matlab.ui.control.Label
        giveinputdataButton            matlab.ui.control.Button
        AppSettingsTab                 matlab.ui.container.Tab
        ClevelsPulsewidthEditField     matlab.ui.control.NumericEditField
        ClevelsPulsewidthEditFieldLabel  matlab.ui.control.Label
        intersequencemsEditField       matlab.ui.control.NumericEditField
        GoLiveCheckBox                 matlab.ui.control.CheckBox
        intersequencemsEditFieldLabel  matlab.ui.control.Label
        StimdurationmsEditField        matlab.ui.control.NumericEditField
        StimdurationmsEditFieldLabel   matlab.ui.control.Label
        InterphasegapsEditField        matlab.ui.control.NumericEditField
        InterphasegapsEditFieldLabel   matlab.ui.control.Label
        StimphasesEditField            matlab.ui.control.NumericEditField
        StimphasesEditFieldLabel       matlab.ui.control.Label
        ModeDropDown                   matlab.ui.control.DropDown
        ModeDropDownLabel              matlab.ui.control.Label
        PitchRankingDecisionRepetitionEditField  matlab.ui.control.NumericEditField
        PitchRankingDecisionRepetitionEditFieldLabel  matlab.ui.control.Label
        PitchRankingExperimentLabel    matlab.ui.control.Label
        Decideafter2unanimousDecisionsCheckBox  matlab.ui.control.CheckBox
        rampingactiveCheckBox          matlab.ui.control.CheckBox
        CalculateasDynamicRangeofMapCheckBox  matlab.ui.control.CheckBox
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)

        Ranking        % This will hold all ranking-related data
        Discrimination % This will hold all discrimination-related data
        TestSeq        % This will hold all Test Sequence-related data 
        IPG
        roundedpossibleFrames

        frame_rate_micros = 64.8; %frame period harcoded to 64.8 - for now hardcoded in μs 
        frame_rate_millis = 64.8/1e3; %frame period harcoded to 64.8 - for now hardcoded in ms 
        possibleFrames % possible Frames for the pps/frame_rate
        fractional_possibleFrames % 0.87 of possibleFrames
        stepsize 
        interpulsegaps


        ComfortValues double = zeros(1,22); % preallocate Comforts
        ThresholdValues double = zeros(1,22); % preallocate Threshold
        FlagElectrodeCheckBoxValues logical = false(1,22); % preallocate Flags

        PatientID
        Directory
        
        startPath = pwd; % present working directory
        % startPath = 'C:\'; % main C folder
        
        
        patientFolder
        administrator = {'admin', 'admin'};
        isLoginSuccess = 0; 

    end
    
    methods (Access = private)

        function MapValueChanged(app, event, valuesArrayName)
            src = event.Source;
            value = src.Value;
            name = src.Tag;   % e.g. "C7"
        
            idx = ExtractIndexFromTag(app, name);  

            if valuesArrayName == "FlagElectrodeCheckBox"
                app.(valuesArrayName)(idx) = value; % Store true/false
                % Add any specific checkbox logic here (e.g., disabling the row)
                return; 
            end

            app.(valuesArrayName)(idx) = value;   % store value

            % 1. Define the rules once (could even be in your startupFcn)
            rules.ComfortValues   = struct('opposite', "Threshold", 'limit', 2);
            rules.ThresholdValues = struct('opposite', "Comfort",   'limit', 1);
            
            if isfield(rules, valuesArrayName)
                % 2. Look up the settings based on the input name
                config = rules.(valuesArrayName);
                
                % 3. Apply the values
                compName = config.opposite + "_" + idx;
                app.(compName).Limits(config.limit) = value;
            end
        end

        function idx = ExtractIndexFromTag(~, tag)
            % Extracts the numeric part of a tag string
            numStr = regexp(tag, '\d+', 'match');  % find all numbers
            idx = str2double(numStr{1});           % convert first match to number
        end

        function setupMapValues(app, prefix, valuesArrayName)
            for i = 1:22
                compName = prefix + "_" + i;
                app.(compName).Tag = prefix(1)+ "_" + i; % C or T or F
                app.(compName).ValueChangedFcn = @(src,event) MapValueChanged(app,event,valuesArrayName);
                app.(valuesArrayName)(i) = app.(compName).Value;
            end
        end

        function oppositeArrayName = GetOppositeArray(~, valuesArrayName) % not used currently
            % Returns the opposite array name for sliders/values
            switch valuesArrayName
                case "ComfortValues"
                    oppositeArrayName = "ThresholdValues";
                case "ThresholdValues"
                    oppositeArrayName = "ComfortValues";
                otherwise
                    errordlg("No valuesArrayName") % or error/warning if needed
            end
        end

        function stopFlag = CheckMapLevels(app, idx, name, value, valuesArrayName) % error if T is over C % not used rn  
            stopFlag = false;

            switch valuesArrayName
                case "ThresholdValues"
                    if value > app.ComfortValues(idx) 
                        errordlg(name + " is over Comfort!")
                        stopFlag = true;
                    end
                case "ComfortValues"
                    if value < app.ThresholdValues(idx) 
                        errordlg(name + " is under Threshold!")
                        stopFlag = true;
                    end
            end
        end
        
        function CreatePatientFolder(app) 
            tString = GetCurrentTimeStamp(app);
            currentFolder = app.PatientID + tString;

            app.patientFolder = fullfile(app.Directory, currentFolder);
            if ~exist(app.patientFolder, 'dir')
                mkdir(app.patientFolder);
            end
        end
        
        function tString = GetCurrentTimeStamp(~)
            t = datetime('now');
            tString = datestr(t, 'HH.MM_dd_MM_yyyy'); % Format: HH.MM_dd_MM_yyyy
        end

        function GenerateSequenceZoomedPlot(app)
            ZoomFactor = app.ZoomfactorSpinner.Value; 
            runlength = 100; % ms
            
            burst_struct = GenerateBurstStruct(app);

            % 3. Construct the full sequence
            seq_numbursts = ceil(runlength / app.RateEditField_inms.Value);
            seq_current = repmat(burst_struct, 1, seq_numbursts);

            time_ms = (0:length(seq_current)-1) * app.frame_rate_millis;

            % 3. Plot using the time vector
            plot(app.UIAxes_only, time_ms, seq_current);
            
            % 4. Adjust the X-Limit to show the zoomed portion in milliseconds
            % This will now correctly show the 3.333ms bursts
            max_time = max(time_ms);
            xlim(app.UIAxes_only, [0, max_time / ZoomFactor]);
            
            % Optional: Label your axes so you can verify the units
            xlabel(app.UIAxes_only, 'Time (ms)');
            ylabel(app.UIAxes_only, 'Amplitude');

        end
        
        function GenerateSeqTabStart(app)
            app.IPG = struct();
            app.IPG.isChanged = 0;
            app.IPG.stepsize = app.frame_rate_millis;

            snapped_value = round(app.RateEditField_inms.Value / app.frame_rate_millis) * app.frame_rate_millis;
            app.RateEditField_inms.Value = round(snapped_value, 4);
            app.RateEditField_inpps.Value = 1e3/app.RateEditField_inms.Value; % get in pps 

            app.WaitPeriodEditField.Limits(2) = app.RateEditField_inms.Value;
            app.WaitPeriodEditField.Value = app.RateEditField_inms.Value-sum(app.interpulsegaps); % update WaitperiodEditfield

            app.FrameperiodLabel.Text = "Frameperiod [μs]: " + app.frame_rate_micros; % this needs to be updated if not hardcoded anymore

            UpdateMaxPulses(app);
            UpdateInterpulseGaps(app);
        end
        
        function UpdateInterpulseGaps(app)
            
            gapString = strjoin(string(app.interpulsegaps), ', '); % Convert the numeric array to strings, then join them with a comma
            
            app.Label.Text = "Interpulsegaps: [" + gapString + "]"; % update the label 
        end
        
        function UpdateMaxPulses(app)

            app.possibleFrames = app.RateEditField_inms.Value/app.frame_rate_millis; % calc max possible frames
            app.roundedpossibleFrames = floor(app.possibleFrames);

            app.fractional_possibleFrames = round(rem(app.possibleFrames, 1), 4); % get the fractional

            app.MaxPulsesLabel.Text = "Maximum Pulses: " + app.possibleFrames; % write the label 

            app.PulsesperburstEditField.Limits(2) = app.roundedpossibleFrames; % limit the max amount of pulses
        end
        
        function [multipleofFrame, middleNumofFrames] = GetbiggestpossiblemultipleofFrameRate(app)
            
            rawIpgValue = app.RateEditField_inms.Value / (app.PulsesperburstEditField.Value-1);

            multipleofFrame = floor(rawIpgValue/app.IPG.stepsize) * app.IPG.stepsize;

            numIPG = app.PulsesperburstEditField.Value-1;
            if numIPG < 1, numIPG = 1; end

            maxIPG = round(app.frame_rate_millis * ((floor(app.roundedpossibleFrames / numIPG) * numIPG)/numIPG), 4); % interpulsegap Limit
               
            if multipleofFrame > maxIPG, multipleofFrame = maxIPG; end

            middleNumofFrames = round((rawIpgValue/app.IPG.stepsize)/2) * app.IPG.stepsize;

        end
        
        function ResetInterpulseGap(app)
            app.interpulsegaps(:) = app.InterpulsegapEditField.Value;
            UpdateInterpulseGaps(app);
        end
        
        function GenerateFileName(app) % check better naming possiblity
                        
            gapString = strjoin(string(app.interpulsegaps), ', ');
            equalinterpulsegaps = sprintf('%.4f', app.InterpulsegapEditField.Value); % maybe later file to long 
            isUniform = all(app.interpulsegaps(:) == app.interpulsegaps(1));

            % Determine the filename based on pulse count and uniformity
            if app.PulsesperburstEditField.Value == 1
                % Single pulse: No gap (D) needed in filename
                app.FilenameEditField.Value = strcat('R', num2str(app.RateEditField_inms.Value), '_P', num2str(app.PulsesperburstEditField.Value), ...
                    '_W', num2str(app.WaitPeriodEditField.Value));
                    
            elseif isUniform
                % Multiple pulses, all gaps are the same
                app.FilenameEditField.Value = strcat('R', num2str(app.RateEditField_inms.Value), '_P', num2str(app.PulsesperburstEditField.Value), ...
                    '_G', num2str(equalinterpulsegaps), '_W', num2str(app.WaitPeriodEditField.Value));
                    
            else
                % Not uniform (and implied pulses ~= 1)
                app.FilenameEditField.Value = strcat('R', num2str(app.RateEditField_inms.Value), '_P', num2str(app.PulsesperburstEditField.Value), ...
                    '_G', num2str(gapString), '_W', num2str(app.WaitPeriodEditField.Value));
            end

        end
        
        function burst_struct = GenerateBurstStruct(app)

            % 1. Calculate the total frames in ONE burst period
            % This ensures the pulses are spaced out correctly when repeated
            frames_per_burst = round(app.RateEditField_inms.Value / app.frame_rate_millis); % should be an integer alr, round is just tomake sure 
            burst_struct = zeros(1, frames_per_burst); % amount of frames in the struct
            
            burst_numpulses = app.PulsesperburstEditField.Value; % number of pulses
            
            % 2. Calculate pulse positions relative to the start of the burst
            if ~isempty(app.interpulsegaps) && burst_numpulses > 0

                current_frame = 1; % First pulse at frame 1
                burst_struct(current_frame) = 1; % Set amplitude

                for i = 1:(burst_numpulses - 1)

                    % Move forward by the gap + 1 pulse width
                    gap_in_frames = round(app.interpulsegaps(i) / app.frame_rate_millis); % also int alr % minus frame to get actual ipg without the pulsetime
                    current_frame = current_frame + gap_in_frames;
                
                    % Safety check: don't exceed the burst period length
                    if current_frame <= frames_per_burst
                        burst_struct(current_frame) = 1;
                    end
                end

            end
        end
        
        function resetMapValues(app, prefix, valuesArrayName)
            % 1. Define the rules
            % 'limit' here refers to the boundary we want to reset for the OTHER component
            rules.ComfortValues   = struct('startValue', 255,   'resetLimits', [0 255], 'isNumeric', true);
            rules.ThresholdValues = struct('startValue', 0,   'resetLimits', [0 255], 'isNumeric', true);
            rules.FlagElectrodeCheckBox = struct('startValue', 0, 'isNumeric', false);
            
            if ~isfield(rules, valuesArrayName)
                return; % Safety check
            end
            
            config = rules.(valuesArrayName);
            
            for i = 1:22
                compName = prefix + "_" + i;
                target = app.(compName);

                if config.isNumeric
                % 1. Open limits to avoid "out of range" errors
                target.Limits = [0 255];
                % 2. Set numeric value
                target.Value = config.startValue;
                % 4. Sync the internal array
                app.(valuesArrayName)(i) = target.Value;
                else
                    % 3. Set logical value for checkboxes
                    target.Value = config.startValue;
                    % 4. Sync the internal array
                    app.(valuesArrayName) = target.Value;
                end
                
                
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            setupMapValues(app, "Comfort", "ComfortValues");
            setupMapValues(app, "Threshold", "ThresholdValues");
            setupMapValues(app, "FlagElectrodeCheckBox", "FlagElectrodeCheckBoxValues")

            app.PatientID = app.PatientIDEditField.Value;
            app.DirectoryLabel.Text = "Directory: " + app.startPath;

            GenerateSeqTabStart(app);
        end

        % Value changed function: PatientIDEditField
        function PatientIDEditFieldValueChanged(app, event)
            value = app.PatientIDEditField.Value;
            app.PatientID = value;
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)

            % Open the folder selection dialog
            folderName = uigetdir(app.startPath, 'Select the Directory');
            app.Directory = folderName;
            app.DirectoryLabel.Text = ["Directory:", app.Directory];
        end

        % Callback function
        function OversightTabGroupSelectionChanged(app, event)
            % to activate Log in just put this callback in the
            % selectionChangedFcn on app.OversightTabGroup
            if app.isLoginSuccess == 0 
                app.OversightTabGroup.SelectedTab = app.MainTab;
                errordlg('You must log in to access this tab','Error')
            end
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            prompt = {'Enter Username:','Enter Password:'};
            dlgtitle = 'Log in';
            dims = [1 35];
            definput = {'',''};
            loginDetails = inputdlg(prompt,dlgtitle,dims,definput);

            userEntry = string(strtrim(loginDetails));
            administartorEntry = string(strtrim(app.administrator));

            if isempty(loginDetails), return; end

            if isequal(userEntry(:), administartorEntry(:)), app.isLoginSuccess = 1; app.LoginButton.Enable = false; app.LoginButton.Text = "Logged in"; end
        end

        % Value changed function: FlagunflagallElectrodesCheckBox
        function FlagunflagallElectrodesCheckBoxValueChanged(app, event)
            value = app.FlagunflagallElectrodesCheckBox.Value;

            app.FlagElectrodeCheckBoxValues(:) = value;

            for i = 1:length(app.FlagElectrodeCheckBoxValues)
                compName = sprintf('FlagElectrodeCheckBox_%d', i); 
                % Update the UI component directly
                app.(compName).Value = value;
            end
            
        end

        % Value changed function: PulsesperburstEditField
        function PulsesperburstEditFieldValueChanged(app, event)
            value = app.PulsesperburstEditField.Value;
            numIPGs = value - 1;
            app.WaitPeriodEditField.Limits(2) = app.RateEditField_inms.Value; % biggest possbile wait for those many pulses

            if value > 1
                app.possibleFrames = app.RateEditField_inms.Value/app.frame_rate_millis;
                app.roundedpossibleFrames = floor(app.possibleFrames);

                app.InterpulsegapEditField.Enable = "on";
                app.InputgapsButton.Enable = "on";
                numIPG = app.PulsesperburstEditField.Value-1;
                if numIPG < 1, numIPG = 1; end
                app.InterpulsegapEditField.Limits(2) = round(app.frame_rate_millis * ((floor(app.roundedpossibleFrames / numIPG) * numIPG)/numIPG), 4); % Limit the Inputfield
                            
                [~, middleNumofFrame] = GetbiggestpossiblemultipleofFrameRate(app);
                ipgValue = middleNumofFrame;

                app.interpulsegaps = ones(1,numIPGs) * ipgValue; % newly initiates the Array

                app.InterpulsegapEditField.Value = round(ipgValue, 4);

                app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);

                UpdateInterpulseGaps(app); % writes the IPG Label 
            else
                app.interpulsegaps = [];
                app.WaitPeriodEditField.Value = app.RateEditField_inms.Value;
                UpdateInterpulseGaps(app);
                app.InterpulsegapEditField.Enable = "off";
                app.InputgapsButton.Enable = "off";
            end

            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Value changed function: RateEditField_inms
        function RateEditField_inmsValueChanged(app, event)
            % Calculate the nearest multiple
            snapped_value = round(app.RateEditField_inms.Value / app.frame_rate_millis) * app.frame_rate_millis;
            
            % Update the UI so the user sees the actual value being used
            app.RateEditField_inms.Value = round(snapped_value, 4);
            
            app.RateEditField_inpps.Value = 1e3/app.RateEditField_inms.Value; % get in pps 

            UpdateMaxPulses(app);

            numIPG = app.PulsesperburstEditField.Value-1;
            if numIPG < 1, numIPG = 1; end
            app.InterpulsegapEditField.Limits(2) = round(app.frame_rate_millis * ((floor(app.roundedpossibleFrames / numIPG) * numIPG)/numIPG), 4); % Limit the Inputfield
            
            [~, middleNumofFrame] = GetbiggestpossiblemultipleofFrameRate(app);
            ipgValue = middleNumofFrame;

            if ~isempty(app.interpulsegaps)
                app.interpulsegaps = ones(1,numIPG) * ipgValue;
                app.InterpulsegapEditField.Value = ipgValue;
                UpdateInterpulseGaps(app); % writes the IPG Label 
            end    

            app.WaitPeriodEditField.Limits(2) = app.RateEditField_inms.Value; % limit the waitperiod to the Rate 
            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);

            ResetInterpulseGap(app);
            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Value changed function: RateEditField_inpps
        function RateEditField_inppsValueChanged(app, event)

            app.RateEditField_inms.Value = 1e3/app.RateEditField_inpps.Value; % get in ms 

            snapped_value = round(app.RateEditField_inms.Value / app.frame_rate_millis) * app.frame_rate_millis;
            
            % Update the UI so the user sees the actual value being used
            app.RateEditField_inms.Value = round(snapped_value, 4);
            
            app.RateEditField_inpps.Value = 1e3/app.RateEditField_inms.Value; % get in pps 

           
            UpdateMaxPulses(app);

            [~, middleNumofFrame] = GetbiggestpossiblemultipleofFrameRate(app);
            ipgValue = middleNumofFrame;
            
            numIPG = app.PulsesperburstEditField.Value-1;
            if numIPG < 1, numIPG = 1; end
            app.InterpulsegapEditField.Limits(2) = round(app.frame_rate_millis * ((floor(app.roundedpossibleFrames / numIPG) * numIPG)/numIPG), 4); % Limit the Inputfield

            if ~isempty(app.interpulsegaps)
                app.interpulsegaps = ones(1,numIPG) * ipgValue;
                app.InterpulsegapEditField.Value = ipgValue;
                UpdateInterpulseGaps(app); % writes the IPG Label 
            end % newly initiates the Array        
            

            app.WaitPeriodEditField.Limits(2) = app.RateEditField_inms.Value; % limit the waitperiod to the Rate 
            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);

            ResetInterpulseGap(app);
            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Value changed function: InterpulsegapEditField
        function InterpulsegapEditFieldValueChanged(app, event)
            if app.InterpulsegapEditField.Value < app.frame_rate_millis, app.InterpulsegapEditField.Value = app.frame_rate_millis; end

            value = round(app.InterpulsegapEditField.Value / app.IPG.stepsize) * app.IPG.stepsize;

            app.InterpulsegapEditField.Value = round(value, 4); % should calc the next working multiple of app.framerate

            app.interpulsegaps(:) = app.InterpulsegapEditField.Value; % all of ipg array gets set to 

            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);

            UpdateInterpulseGaps(app); % writes the IPG Label 

            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Button pushed function: InputgapsButton
        function InputgapsButtonPushed(app, event)
            persistent Input

            numIPGs = app.PulsesperburstEditField.Value - 1;
            defaultInput = [];

            for i = 1:numIPGs
                defaultInput = strcat(defaultInput,num2str(app.InterpulsegapEditField.Value));
                if i<numIPGs, defaultInput = strcat(defaultInput,","); end
            end

            if isempty(Input) || app.IPG.isChanged
                app.IPG.isChanged = 0;
                Input = defaultInput;
            end
           
            prompt = 'Enter desired IPGs:';
            dlgtitle = 'Input multiple IPGs';
            dims = [1 170];
            answer = inputdlg(prompt,dlgtitle,dims,Input);

            userInput = str2num(answer{1}); 

            numIPG = app.PulsesperburstEditField.Value-1;
            if numIPG < 1, numIPG = 1; end
            maxIPG = app.frame_rate_millis*(floor(app.roundedpossibleFrames / numIPG) * numIPG)/(app.PulsesperburstEditField.Value-1); % interpulsegap Limit

            for i = 1:length(userInput)
                userInput(i) = round(userInput(i) / app.IPG.stepsize) * app.IPG.stepsize;
                if userInput(i) > maxIPG
                    userInput(i) = maxIPG;
                end
            end
            
            Input = join(string(userInput), ", ");   

            disp(userInput)
            disp(sum(userInput))

            % validSyntax = ~isempty(regexp(userInput, '^\{\s*(\[\s*\d*\.?\d+\s*\]\s*,?\s*)+\}$', 'once'));
            % if ~validSyntax
            %     errordlg(sprintf('Invalid syntax. Use format like: {[1],[1.3],[5.33]}\nOnly positive and numeric values.'));
            %     return;
            % end
               
            % WaitPeriod = app.RateEditField_inms.Value-sum(userInput);
            % 
            % if WaitPeriod < 0
            %     errordlg(sprintf('Wait Period time wold be below 0! \nChange Period, Gap or Interpulse time'));
            %     Input = defaultInput; 
            %     return;
            % else 
            %     app.WaitPeriodEditField.Value = app.RateEditField_inms.Value-sum(userInput);
            % end


            app.interpulsegaps = userInput;
            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);
            UpdateInterpulseGaps(app); % writes the IPG Label 

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Value changed function: WaitPeriodEditField
        function WaitPeriodEditFieldValueChanged(app, event)
            totalGap = app.RateEditField_inms.Value - app.WaitPeriodEditField.Value;
            numGaps = app.PulsesperburstEditField.Value - 1;
            rawGap = totalGap / numGaps;
          
 
            if numGaps > 0
                snappedIpg = round(rawGap / app.IPG.stepsize) * app.IPG.stepsize;
    
                numIPG = app.PulsesperburstEditField.Value-1;
                if numIPG < 1, numIPG = 1; end
                maxIPG = app.frame_rate_millis*(floor(app.roundedpossibleFrames / numIPG) * numIPG)/(app.PulsesperburstEditField.Value-1); % interpulsegap Limit
    
                if snappedIpg > maxIPG, snappedIpg = maxIPG; end

            else
                % Single pulse logic: Gap is effectively zero or N/A
                snappedIpg = 0;
            end

            app.InterpulsegapEditField.Value = round(snappedIpg, 4); 
            app.interpulsegaps(:) = snappedIpg; % all of ipg array gets set to 
            

            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value - (snappedIpg * numGaps), 4);

            UpdateInterpulseGaps(app); % writes the IPG Label 
            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            
            mainfolder = app.Directory;
            subfolder = "Burst_structs";

            specifiedfolder = fullfile(mainfolder, subfolder);

            if ~exist(specifiedfolder, "dir")
                mkdir(specifiedfolder)
            end

            burst_struct = GenerateBurstStruct(app);
            defaultLocation = fullfile(specifiedfolder, strcat(app.FilenameEditField.Value, '.mat'));
            
            % 1. Open the dialog
            [file, folder] = uiputfile(defaultLocation, 'Save Burst Structure');
            
            % 2. Check if the user actually selected a file (did not click cancel)
            if ischar(file) || isstring(file)
                % 3. Use fullfile for cross-platform compatibility (Windows/Mac/Linux)
                fullSavePath = fullfile(folder, file);
                
                % 4. Save the variable
                save(fullSavePath, 'burst_struct');
                
                % Optional: Provide feedback to the user
                fprintf('Burst_struct file saved successfully: %s\n', fullSavePath);
            else
                % User clicked cancel
                disp('Save cancelled by user.');
            end
        end

        % Value changed function: ZoomfactorSpinner
        function ZoomfactorSpinnerValueChanged(app, event)
            GenerateSequenceZoomedPlot(app);
        end

        % Button pushed function: resetMapButton
        function resetMapButtonPushed(app, event)
            resetMapValues(app, "Comfort", "ComfortValues");
            resetMapValues(app, "Threshold", "ThresholdValues");
            resetMapValues(app, "FlagElectrodeCheckBox", "FlagElectrodeCheckBoxValues")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OversightTabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.OversightTabGroup);
            app.MainTab.Title = 'Main';
            app.PatientIDEditFieldLabel = uilabel(app.MainTab);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.MainTab, 'text');
            app.PatientIDEditField.ValueChangedFcn = createCallbackFcn(app, @PatientIDEditFieldValueChanged, true);
            app.PatientIDEditField.Value = 'insert_ID';
            app.ExperimentTabGroup = uitabgroup(app.MainTab);
            app.TestSequenceTab = uitab(app.ExperimentTabGroup);
            app.TestSequenceTab.Title = 'Test Sequence';
            app.UIAxes_3 = uiaxes(app.TestSequenceTab);
            title(app.UIAxes_3, 'Sequence')
            xlabel(app.UIAxes_3, 'Time [ms]')
            ylabel(app.UIAxes_3, 'Amplitude [CL]')
            app.ChooseSequenceFileButton = uibutton(app.TestSequenceTab, 'push');
            app.ChooseSequenceFileButton.Text = 'Choose Sequence File';
            app.SequenceNameLabel = uilabel(app.TestSequenceTab);
            app.SequenceNameLabel.Text = 'Sequence Name ';
            app.ElectrodesButton = uibutton(app.TestSequenceTab, 'push');
            app.ElectrodesButton.Text = 'Electrodes';
            app.RunTest = uibutton(app.TestSequenceTab, 'push');
            app.RunTest.Text = 'Run';
            app.AmplitudesButton = uibutton(app.TestSequenceTab, 'push');
            app.AmplitudesButton.Text = 'Amplitudes';
            app.DiscrimationExpTab = uitab(app.ExperimentTabGroup);
            app.DiscrimationExpTab.Title = 'Discrimation Exp';
            app.UIAxes = uiaxes(app.DiscrimationExpTab);
            title(app.UIAxes, 'Sequence')
            xlabel(app.UIAxes, 'Time [ms]')
            ylabel(app.UIAxes, 'Amplitude [CL]')
            app.RunDiscrimination = uibutton(app.DiscrimationExpTab, 'push');
            app.RunDiscrimination.Text = 'Run';
            app.ChooseSequenceFolderButton_2 = uibutton(app.DiscrimationExpTab, 'push');
            app.ChooseSequenceFolderButton_2.Text = 'Choose Sequence Folder';
            app.FolderNameLabel = uilabel(app.DiscrimationExpTab);
            app.FolderNameLabel.Text = 'Folder Name ';
            app.UITable = uitable(app.DiscrimationExpTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.RunsEditField_2Label = uilabel(app.DiscrimationExpTab);
            app.RunsEditField_2Label.Text = 'Runs';
            app.RunsEditField_2 = uieditfield(app.DiscrimationExpTab, 'text');
            app.ElectrodesButton_2 = uibutton(app.DiscrimationExpTab, 'push');
            app.ElectrodesButton_2.Text = 'Electrodes';
            app.AmplitudesButton_2 = uibutton(app.DiscrimationExpTab, 'push');
            app.AmplitudesButton_2.Text = 'Amplitudes';
            app.RandomTokenOrderCheckBox_2 = uicheckbox(app.DiscrimationExpTab);
            app.RandomTokenOrderCheckBox_2.Text = 'Random Token Order';
            app.TokenpatternDropDown_2Label = uilabel(app.DiscrimationExpTab);
            app.TokenpatternDropDown_2Label.Text = 'Token pattern ';
            app.TokenpatternDropDown_2 = uidropdown(app.DiscrimationExpTab);
            app.TokenpatternDropDown_2.Items = {'Odd Token Out ', 'A - B ', '4I2AFC'};
            app.TokenpatternDropDown_2.Value = 'Odd Token Out ';
            app.TokenOrderDropDown_2Label = uilabel(app.DiscrimationExpTab);
            app.TokenOrderDropDown_2Label.Text = 'Token Order';
            app.TokenOrderDropDown_2 = uidropdown(app.DiscrimationExpTab);
            app.TokenOrderDropDown_2.Items = {'A - B - A', 'B - A - A', 'A - A - B'};
            app.TokenOrderDropDown_2.Value = 'A - B - A';
            app.RankingExpTab = uitab(app.ExperimentTabGroup);
            app.RankingExpTab.Title = 'Ranking Exp';
            app.UIAxes_2 = uiaxes(app.RankingExpTab);
            title(app.UIAxes_2, 'Sequence')
            xlabel(app.UIAxes_2, 'Time [ms]')
            ylabel(app.UIAxes_2, 'Amplitude [CL]')
            app.RunRanking = uibutton(app.RankingExpTab, 'push');
            app.RunRanking.Text = 'Run';
            app.ChooseSequenceFolderButton_3 = uibutton(app.RankingExpTab, 'push');
            app.ChooseSequenceFolderButton_3.Text = 'Choose Sequence Folder';
            app.FolderNameLabel_2 = uilabel(app.RankingExpTab);
            app.FolderNameLabel_2.Text = 'Folder Name ';
            app.UITable_2 = uitable(app.RankingExpTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.RunsEditFieldLabel = uilabel(app.RankingExpTab);
            app.RunsEditFieldLabel.Text = 'Runs';
            app.RunsEditField = uieditfield(app.RankingExpTab, 'text');
            app.ElectrodesButton_3 = uibutton(app.RankingExpTab, 'push');
            app.ElectrodesButton_3.Text = 'Electrodes';
            app.AmplitudesButton_3 = uibutton(app.RankingExpTab, 'push');
            app.AmplitudesButton_3.Text = 'Amplitudes';
            app.ElectrodesEditFieldLabel = uilabel(app.RankingExpTab);
            app.ElectrodesEditFieldLabel.Text = 'Electrodes';
            app.ElectrodesEditField = uieditfield(app.RankingExpTab, 'text');
            app.AmplitudesEditFieldLabel = uilabel(app.RankingExpTab);
            app.AmplitudesEditFieldLabel.Text = 'Amplitudes';
            app.AmplitudesEditField = uieditfield(app.RankingExpTab, 'text');
            app.PickfilesButton = uibutton(app.RankingExpTab, 'push');
            app.PickfilesButton.Text = 'Pick files';
            app.RandomTokenOrderCheckBox = uicheckbox(app.RankingExpTab);
            app.RandomTokenOrderCheckBox.Text = 'Random Token Order';
            app.TokenpatternDropDownLabel = uilabel(app.RankingExpTab);
            app.TokenpatternDropDownLabel.Text = 'Token pattern ';
            app.TokenpatternDropDown = uidropdown(app.RankingExpTab);
            app.TokenpatternDropDown.Items = {'Odd Token Out ', 'A - B ', '4I2AFC'};
            app.TokenpatternDropDown.Value = 'Odd Token Out ';
            app.TokenOrderDropDownLabel = uilabel(app.RankingExpTab);
            app.TokenOrderDropDownLabel.Text = 'Token Order';
            app.TokenOrderDropDown = uidropdown(app.RankingExpTab);
            app.TokenOrderDropDown.Items = {'A - B - A', 'B - A - A', 'A - A - B'};
            app.TokenOrderDropDown.Value = 'A - B - A';
            app.Threshold_1 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_1.Limits = [0 255];
            app.Threshold_2 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_2.Limits = [0 255];
            app.Threshold_3 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_3.Limits = [0 255];
            app.Threshold_4 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_4.Limits = [0 255];
            app.Threshold_5 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_5.Limits = [0 255];
            app.Threshold_6 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_6.Limits = [0 255];
            app.Threshold_7 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_7.Limits = [0 255];
            app.Threshold_8 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_8.Limits = [0 255];
            app.Threshold_9 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_9.Limits = [0 255];
            app.Threshold_10 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_10.Limits = [0 255];
            app.Threshold_11 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_11.Limits = [0 255];
            app.Threshold_12 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_12.Limits = [0 255];
            app.Threshold_13 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_13.Limits = [0 255];
            app.Threshold_14 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_14.Limits = [0 255];
            app.Threshold_15 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_15.Limits = [0 255];
            app.Threshold_16 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_16.Limits = [0 255];
            app.Threshold_17 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_17.Limits = [0 255];
            app.Threshold_18 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_18.Limits = [0 255];
            app.Threshold_19 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_19.Limits = [0 255];
            app.Threshold_20 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_20.Limits = [0 255];
            app.Threshold_21 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_21.Limits = [0 255];
            app.Threshold_22 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_22.Limits = [0 255];
            app.Comfort_1 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_1.Limits = [0 255];
            app.Comfort_1.Value = 255;
            app.Comfort_2 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_2.Limits = [0 255];
            app.Comfort_2.Value = 255;
            app.Comfort_3 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_3.Limits = [0 255];
            app.Comfort_3.Value = 255;
            app.Comfort_4 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_4.Limits = [0 255];
            app.Comfort_4.Value = 255;
            app.Comfort_5 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_5.Limits = [0 255];
            app.Comfort_5.Value = 255;
            app.Comfort_6 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_6.Limits = [0 255];
            app.Comfort_6.Value = 255;
            app.Comfort_7 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_7.Limits = [0 255];
            app.Comfort_7.Value = 255;
            app.Comfort_8 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_8.Limits = [0 255];
            app.Comfort_8.Value = 255;
            app.Comfort_9 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_9.Limits = [0 255];
            app.Comfort_9.Value = 255;
            app.Comfort_10 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_10.Limits = [0 255];
            app.Comfort_10.Value = 255;
            app.Comfort_11 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_11.Limits = [0 255];
            app.Comfort_11.Value = 255;
            app.Comfort_12 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_12.Limits = [0 255];
            app.Comfort_12.Value = 255;
            app.Comfort_13 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_13.Limits = [0 255];
            app.Comfort_13.Value = 255;
            app.Comfort_14 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_14.Limits = [0 255];
            app.Comfort_14.Value = 255;
            app.Comfort_15 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_15.Limits = [0 255];
            app.Comfort_15.Value = 255;
            app.Comfort_16 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_16.Limits = [0 255];
            app.Comfort_16.Value = 255;
            app.Comfort_17 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_17.Limits = [0 255];
            app.Comfort_17.Value = 255;
            app.Comfort_18 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_18.Limits = [0 255];
            app.Comfort_18.Value = 255;
            app.Comfort_19 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_19.Limits = [0 255];
            app.Comfort_19.Value = 255;
            app.Comfort_20 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_20.Limits = [0 255];
            app.Comfort_20.Value = 255;
            app.Comfort_21 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_21.Limits = [0 255];
            app.Comfort_21.Value = 255;
            app.Comfort_22 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_22.Limits = [0 255];
            app.Comfort_22.Value = 255;
            app.ThresholdLabel = uilabel(app.MainTab);
            app.ThresholdLabel.Text = 'Threshold';
            app.ComfortLabel = uilabel(app.MainTab);
            app.ComfortLabel.Text = 'Comfort';
            app.FlaggedLabel = uilabel(app.MainTab);
            app.FlaggedLabel.Text = 'Flagged';
            app.FlagElectrodeCheckBox_1 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_1.Text = '1';
            app.FlagElectrodeCheckBox_2 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_2.Text = '2';
            app.FlagElectrodeCheckBox_3 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_3.Text = '3';
            app.FlagElectrodeCheckBox_4 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_4.Text = '4';
            app.FlagElectrodeCheckBox_5 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_5.Text = '5';
            app.FlagElectrodeCheckBox_6 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_6.Text = '6';
            app.FlagElectrodeCheckBox_7 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_7.Text = '7';
            app.FlagElectrodeCheckBox_8 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_8.Text = '8';
            app.FlagElectrodeCheckBox_9 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_9.Text = '9';
            app.FlagElectrodeCheckBox_10 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_10.Text = '10';
            app.FlagElectrodeCheckBox_11 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_11.Text = '11';
            app.FlagElectrodeCheckBox_12 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_12.Text = '12';
            app.FlagElectrodeCheckBox_13 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_13.Text = '13';
            app.FlagElectrodeCheckBox_14 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_14.Text = '14';
            app.FlagElectrodeCheckBox_15 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_15.Text = '15';
            app.FlagElectrodeCheckBox_16 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_16.Text = '16';
            app.FlagElectrodeCheckBox_17 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_17.Text = '17';
            app.FlagElectrodeCheckBox_18 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_18.Text = '18';
            app.FlagElectrodeCheckBox_19 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_19.Text = '19';
            app.FlagElectrodeCheckBox_20 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_20.Text = '20';
            app.FlagElectrodeCheckBox_21 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_21.Text = '21';
            app.FlagElectrodeCheckBox_22 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_22.Text = '22';
            app.FlagunflagallElectrodesCheckBox = uicheckbox(app.MainTab);
            app.FlagunflagallElectrodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlagunflagallElectrodesCheckBoxValueChanged, true);
            app.FlagunflagallElectrodesCheckBox.Text = 'Flag/unflag all Electrodes';
            app.importMapButton = uibutton(app.MainTab, 'push');
            app.importMapButton.Text = 'import Map';
            app.DirectoryLabel = uilabel(app.MainTab);
            app.DirectoryLabel.Text = 'Directory: ';
            app.LoginButton = uibutton(app.MainTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Log in ';
            app.SelectDirectoryButton = uibutton(app.MainTab, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.resetMapButton = uibutton(app.MainTab, 'push');
            app.resetMapButton.ButtonPushedFcn = createCallbackFcn(app, @resetMapButtonPushed, true);
            app.resetMapButton.Text = 'reset Map';
            app.GenerateSeqTab = uitab(app.OversightTabGroup);
            app.GenerateSeqTab.Title = 'Generate Seq';
            app.UIAxes_only = uiaxes(app.GenerateSeqTab);
            title(app.UIAxes_only, 'Zoomed view')
            xlabel(app.UIAxes_only, 'Time [ms]')
            ylabel(app.UIAxes_only, 'Amplitude [CL]')
            app.SaveAsButton = uibutton(app.GenerateSeqTab, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Tooltip = {'Click "Graph" before saving to generate the correct .mat files'};
            app.SaveAsButton.Text = 'Save As:';
            app.FilenameEditField = uieditfield(app.GenerateSeqTab, 'text');
            app.FilenameEditField.Value = 'Insert_Filename';
            app.InputgapsButton = uibutton(app.GenerateSeqTab, 'push');
            app.InputgapsButton.ButtonPushedFcn = createCallbackFcn(app, @InputgapsButtonPushed, true);
            app.InputgapsButton.Enable = 'off';
            app.InputgapsButton.Text = 'Input gaps';
            app.BurstperiodppsLabel = uilabel(app.GenerateSeqTab);
            app.BurstperiodppsLabel.Text = 'Rate [pps]';
            app.RateEditField_inpps = uieditfield(app.GenerateSeqTab, 'numeric');
            app.RateEditField_inpps.Limits = [0 15432.0988];
            app.RateEditField_inpps.ValueDisplayFormat = '%.0f';
            app.RateEditField_inpps.ValueChangedFcn = createCallbackFcn(app, @RateEditField_inppsValueChanged, true);
            app.RateEditField_inpps.Tooltip = {'Enter burst period in ms'};
            app.RateEditField_inpps.Value = 900;
            app.PulsesperFrameLabel = uilabel(app.GenerateSeqTab);
            app.PulsesperFrameLabel.Text = 'Pulses per Burst';
            app.PulsesperburstEditField = uieditfield(app.GenerateSeqTab, 'numeric');
            app.PulsesperburstEditField.Limits = [1 Inf];
            app.PulsesperburstEditField.RoundFractionalValues = 'on';
            app.PulsesperburstEditField.ValueDisplayFormat = '%.0f';
            app.PulsesperburstEditField.ValueChangedFcn = createCallbackFcn(app, @PulsesperburstEditFieldValueChanged, true);
            app.PulsesperburstEditField.Tooltip = {'Enter number of pulses within a burst. '; 'NOTE: If only 1 pulse is entered, interpulsegap will be ignored'};
            app.PulsesperburstEditField.Value = 1;
            app.InterpulsegapmsLabel = uilabel(app.GenerateSeqTab);
            app.InterpulsegapmsLabel.Text = 'Interpulsegap [ms]';
            app.InterpulsegapEditField = uieditfield(app.GenerateSeqTab, 'numeric');
            app.InterpulsegapEditField.Limits = [0 Inf];
            app.InterpulsegapEditField.ValueDisplayFormat = '%.4f';
            app.InterpulsegapEditField.ValueChangedFcn = createCallbackFcn(app, @InterpulsegapEditFieldValueChanged, true);
            app.InterpulsegapEditField.Enable = 'off';
            app.InterpulsegapEditField.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.InterpulsegapEditField.Value = 0.0648;
            app.BurstGapmsLabel = uilabel(app.GenerateSeqTab);
            app.BurstGapmsLabel.Text = 'Wait-Period [ms]';
            app.WaitPeriodEditField = uieditfield(app.GenerateSeqTab, 'numeric');
            app.WaitPeriodEditField.Limits = [0 Inf];
            app.WaitPeriodEditField.ValueDisplayFormat = '%.4f ';
            app.WaitPeriodEditField.ValueChangedFcn = createCallbackFcn(app, @WaitPeriodEditFieldValueChanged, true);
            app.WaitPeriodEditField.Tooltip = {'Enter burst period in ms'};
            app.WaitPeriodEditField.Value = 1.1111;
            app.FrameperiodLabel = uilabel(app.GenerateSeqTab);
            app.FrameperiodLabel.Text = 'Frame-period: ';
            app.BurstperiodppsLabel_2 = uilabel(app.GenerateSeqTab);
            app.BurstperiodppsLabel_2.Text = 'Rate [ms]';
            app.RateEditField_inms = uieditfield(app.GenerateSeqTab, 'numeric');
            app.RateEditField_inms.Limits = [0.0666 Inf];
            app.RateEditField_inms.ValueDisplayFormat = '%.4f';
            app.RateEditField_inms.ValueChangedFcn = createCallbackFcn(app, @RateEditField_inmsValueChanged, true);
            app.RateEditField_inms.Tooltip = {'Enter burst period in ms'};
            app.RateEditField_inms.Value = 1.1111;
            app.MaxPulsesLabel = uilabel(app.GenerateSeqTab);
            app.MaxPulsesLabel.Text = 'Max Pulses: ';
            app.Label = uilabel(app.GenerateSeqTab);
            app.Label.Text = '';
            app.Label_3 = uilabel(app.GenerateSeqTab);
            app.Label_3.Text = 'Make sure to use multiples of 2 for the generation of the IPGs as uneven numbers are bad for moving the IPGs later';
            app.Label_4 = uilabel(app.GenerateSeqTab);
            app.Label_4.Text = 'The IPG is the actual interpulsegap + 1 pulse (being one frame). same with Wait-Period.';
            app.ZoomfactorSpinnerLabel = uilabel(app.GenerateSeqTab);
            app.ZoomfactorSpinnerLabel.Text = 'Zoomfactor';
            app.ZoomfactorSpinner = uispinner(app.GenerateSeqTab);
            app.ZoomfactorSpinner.Limits = [0 Inf];
            app.ZoomfactorSpinner.ValueChangedFcn = createCallbackFcn(app, @ZoomfactorSpinnerValueChanged, true);
            app.ZoomfactorSpinner.Value = 4;
            app.DataAnalysisTab = uitab(app.OversightTabGroup);
            app.DataAnalysisTab.Title = 'Data Analysis';
            app.giveinputdataButton = uibutton(app.DataAnalysisTab, 'push');
            app.giveinputdataButton.Text = 'give input data';
            app.PatientNameEditFieldLabel = uilabel(app.DataAnalysisTab);
            app.PatientNameEditFieldLabel.Text = 'Patient Name';
            app.PatientNameEditField = uieditfield(app.DataAnalysisTab, 'text');
            app.FileorFoldertouseEditFieldLabel = uilabel(app.DataAnalysisTab);
            app.FileorFoldertouseEditFieldLabel.Text = 'File or Folder to use';
            app.FileorFoldertouseEditField = uieditfield(app.DataAnalysisTab, 'text');
            app.usuallydirectorylikenormallydisorrankinglogfolderLabel = uilabel(app.DataAnalysisTab);
            app.usuallydirectorylikenormallydisorrankinglogfolderLabel.Text = 'usually directory like normally + dis or ranking log folder ';
            app.TabGroup = uitabgroup(app.DataAnalysisTab);
            app.DiscriminationPlotTab = uitab(app.TabGroup);
            app.DiscriminationPlotTab.Title = 'Discrimination Plot ';
            app.RankingPlotTab = uitab(app.TabGroup);
            app.RankingPlotTab.Title = 'Ranking Plot';
            app.UIAxes2 = uiaxes(app.RankingPlotTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.createPlotButton = uibutton(app.RankingPlotTab, 'push');
            app.createPlotButton.Text = 'create Plot';
            app.nameofLogorderorsoLabel = uilabel(app.RankingPlotTab);
            app.nameofLogorderorsoLabel.Text = 'name of Log order or so ';
            app.needautomatedlognamingLabel = uilabel(app.RankingPlotTab);
            app.needautomatedlognamingLabel.Text = 'need automated log naming ';
            app.AppSettingsTab = uitab(app.OversightTabGroup);
            app.AppSettingsTab.Title = 'App Settings';
            app.CalculateasDynamicRangeofMapCheckBox = uicheckbox(app.AppSettingsTab);
            app.CalculateasDynamicRangeofMapCheckBox.Text = 'Calculate as Dynamic Range of Map';
            app.rampingactiveCheckBox = uicheckbox(app.AppSettingsTab);
            app.rampingactiveCheckBox.Text = 'ramping active';
            app.Decideafter2unanimousDecisionsCheckBox = uicheckbox(app.AppSettingsTab);
            app.Decideafter2unanimousDecisionsCheckBox.Text = 'Decide after 2 unanimous Decisions';
            app.Decideafter2unanimousDecisionsCheckBox.Value = true;
            app.PitchRankingExperimentLabel = uilabel(app.AppSettingsTab);
            app.PitchRankingExperimentLabel.Text = 'Pitch Ranking Experiment:';
            app.PitchRankingDecisionRepetitionEditFieldLabel = uilabel(app.AppSettingsTab);
            app.PitchRankingDecisionRepetitionEditFieldLabel.Text = 'Pitch Ranking Decision Repetition ';
            app.PitchRankingDecisionRepetitionEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.PitchRankingDecisionRepetitionEditField.Limits = [0 Inf];
            app.PitchRankingDecisionRepetitionEditField.RoundFractionalValues = 'on';
            app.PitchRankingDecisionRepetitionEditField.Value = 6;
            app.ModeDropDownLabel = uilabel(app.AppSettingsTab);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.AppSettingsTab);
            app.ModeDropDown.Items = {'MP1', 'MP2', 'MP1+2'};
            app.ModeDropDown.Value = 'MP1+2';
            app.StimphasesEditFieldLabel = uilabel(app.AppSettingsTab);
            app.StimphasesEditFieldLabel.Text = 'Stim phase [µs]';
            app.StimphasesEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.StimphasesEditField.Limits = [0 Inf];
            app.StimphasesEditField.ValueDisplayFormat = '%.0f';
            app.StimphasesEditField.Tooltip = {'Enter phase width in µs'};
            app.StimphasesEditField.Value = 25;
            app.InterphasegapsEditFieldLabel = uilabel(app.AppSettingsTab);
            app.InterphasegapsEditFieldLabel.Text = 'Interphase gap [µs]';
            app.InterphasegapsEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.InterphasegapsEditField.Limits = [0 Inf];
            app.InterphasegapsEditField.ValueDisplayFormat = '%.0f';
            app.InterphasegapsEditField.Tooltip = {'Enter interphase gap in µs'};
            app.InterphasegapsEditField.Value = 7;
            app.StimdurationmsEditFieldLabel = uilabel(app.AppSettingsTab);
            app.StimdurationmsEditFieldLabel.Text = 'Stim duration [ms]';
            app.StimdurationmsEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.StimdurationmsEditField.Limits = [0 Inf];
            app.StimdurationmsEditField.Tooltip = {'Enter stim duration in ms'};
            app.StimdurationmsEditField.Value = 500;
            app.intersequencemsEditFieldLabel = uilabel(app.AppSettingsTab);
            app.intersequencemsEditFieldLabel.Text = 'intersequence [ms]';
            app.GoLiveCheckBox = uicheckbox(app.AppSettingsTab);
            app.GoLiveCheckBox.Text = 'Go Live';
            app.GoLiveCheckBox.Value = true;
            app.intersequencemsEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.intersequencemsEditField.Value = 500;
            app.ClevelsPulsewidthEditFieldLabel = uilabel(app.AppSettingsTab);
            app.ClevelsPulsewidthEditFieldLabel.Text = 'C levels Pulse width';
            app.ClevelsPulsewidthEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.ClevelsPulsewidthEditField.Value = 25;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tigerv3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end